classdef brainlab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainLabFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SingleSubjectTab                matlab.ui.container.Tab
        BrainExtractionDeskullingPanel  matlab.ui.container.Panel
        DeskullButton                   matlab.ui.control.Button
        DeskullFactorEditField          matlab.ui.control.EditField
        DeskullFactorEditFieldLabel     matlab.ui.control.Label
        ButtonGroup_ct_or_mri           matlab.ui.container.ButtonGroup
        mri                             matlab.ui.control.RadioButton
        ct                              matlab.ui.control.RadioButton
        LoadDataPanel                   matlab.ui.container.Panel
        EditField_lesion_file           matlab.ui.control.EditField
        Transform8bitButton             matlab.ui.control.Button
        ConvertMEDxFilesButton          matlab.ui.control.Button
        Button_lesion_file              matlab.ui.control.Button
        LesionimageLabel                matlab.ui.control.Label
        EditField_brain_file            matlab.ui.control.EditField
        Button_brain_file               matlab.ui.control.Button
        BrainImageLabel                 matlab.ui.control.Label
        EditField_working_dir           matlab.ui.control.EditField
        Button_working_dir              matlab.ui.control.Button
        WorkingDirectoryLabel           matlab.ui.control.Label
        NormalizationPanel              matlab.ui.container.Panel
        Panel_atlas                     matlab.ui.container.Panel
        NormalizeButton                 matlab.ui.control.Button
        ReloadButton                    matlab.ui.control.Button
        ListBox_atlas                   matlab.ui.control.ListBox
        AtlasListBoxLabel               matlab.ui.control.Label
        Panel_template                  matlab.ui.container.Panel
        ListBox_template                matlab.ui.control.ListBox
        TemplateListBoxLabel            matlab.ui.control.Label
        Panel_heal                      matlab.ui.container.Panel
        CheckBox_interim_output         matlab.ui.control.CheckBox
        AlignmentMethodLabel            matlab.ui.control.Label
        DropDown_selectAlignMethod      matlab.ui.control.DropDown
        RegistrationMethodLabel         matlab.ui.control.Label
        DropDown_selectRegAlg           matlab.ui.control.DropDown
        EditField_smooth_factor         matlab.ui.control.NumericEditField
        SmoothFactorLabel               matlab.ui.control.Label
        CheckBox_heal                   matlab.ui.control.CheckBox
        GroupTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        VLSMTab                         matlab.ui.container.Tab
        CheckBox_mask                   matlab.ui.control.CheckBox
        EditField_Significance          matlab.ui.control.NumericEditField
        SignificanceLevelEditFieldLabel  matlab.ui.control.Label
        EditField_minClustSize          matlab.ui.control.NumericEditField
        MinimalclustersizeEditFieldLabel  matlab.ui.control.Label
        CheckBox_VolumeCorrection       matlab.ui.control.CheckBox
        EditField_v                     matlab.ui.control.NumericEditField
        PeakvoxelvLabel                 matlab.ui.control.Label
        EditField_nperm                 matlab.ui.control.NumericEditField
        permutationsnumberEditFieldLabel  matlab.ui.control.Label
        EditField_minSubj               matlab.ui.control.NumericEditField
        MinimalOverlapEditFieldLabel    matlab.ui.control.Label
        DropDown_MultipleComp           matlab.ui.control.DropDown
        CorrectionforMultipleComparisonsDropDownLabel  matlab.ui.control.Label
        CheckBox_FlipSign               matlab.ui.control.CheckBox
        DropDown_StatisticalMethod      matlab.ui.control.DropDown
        StatisticalMethodDropDownLabel  matlab.ui.control.Label
        DescriptiveTab                  matlab.ui.container.Tab
        CreateROIReportButton           matlab.ui.control.StateButton
        CheckBox_overlay_interim_images  matlab.ui.control.CheckBox
        CreateOverlayImageButton        matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        OutputFolderLabel               matlab.ui.control.Label
        EditField_output_folder         matlab.ui.control.EditField
        Button_output_folder            matlab.ui.control.Button
        LoadPanel                       matlab.ui.container.Panel
        Button_behavior_file_2          matlab.ui.control.Button
        EditField_mask_file             matlab.ui.control.EditField
        MaskFileoptionalLabel           matlab.ui.control.Label
        FlipButton                      matlab.ui.control.Button
        DropDown_FlipDir                matlab.ui.control.DropDown
        LoadButton                      matlab.ui.control.Button
        EditField_behavior_file         matlab.ui.control.EditField
        Button_behavior_file            matlab.ui.control.Button
        BehaviorFileLabel               matlab.ui.control.Label
        EditField_lesions_folder        matlab.ui.control.EditField
        Button_lesions_folder           matlab.ui.control.Button
        NormalizedLesionsFolderLabel    matlab.ui.control.Label
        SelectPanel                     matlab.ui.container.Panel
        EditField_Threshold             matlab.ui.control.EditField
        LesionThresholdEditFieldLabel   matlab.ui.control.Label
        ListBox_SideSelect              matlab.ui.control.ListBox
        DropDown_SideColumn             matlab.ui.control.DropDown
        SideColumnDropDownLabel         matlab.ui.control.Label
        ListBox_Analyze                 matlab.ui.control.ListBox
        AnalyzeListBoxLabel             matlab.ui.control.Label
        Button_RemoveBehavior           matlab.ui.control.Button
        Button_AddBehavior              matlab.ui.control.Button
        ListBox_Available               matlab.ui.control.ListBox
        AvailableListBoxLabel           matlab.ui.control.Label
        DropDown_SubjectColumn          matlab.ui.control.DropDown
        SubjectColumnDropDownLabel      matlab.ui.control.Label
        Panel_status                    matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        BrainLabBrainImageAnalysisToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DefaultWorkingDir = '.\data' % Working Directory
        TemplatesAtlasesDir = '.\Templates_Atlases'
        DefaultBrainFilename = '*.nii.gz'                
        DefaultLesionFilename = '*.nii.gz'
        DefaultBatchTableFilename = '*.csv'
        DefaultBehaviorFilename = '*.csv'
        DefaultSubjectColumn = 'pcode'
        DefaultSideColumn = 'Side'
        SupportedFileFormats = {'*.nii.gz;*.nii;*.hdr','Imaging Files (*.hdr, *.nii, *.nii.gz)'}; %Supported file formats that will be displayed in the dialog box        
        SupportedBatchTableFormats = '*.csv';
        MsgBox = NaN;
        LESYMAP_methods = {'BM',...
                           'BMfast',...
                            'ttest',...
                            'welch',...
                            'ttestslow',...
                            'regres',...
                            'regresfast',...
                            'regresPerm',...
                            'chisq',...
                            'chisqPerm',...
                            'sccan',...
                            'svr'};

        Additional_methods = {'MW',...
                              'MWperm',...
                              '2way',...
                              'MANOVA',...
                              'interaction',...
                              'custom'};

        SupportedMethods = [LESYMAP_methods, Additional_methods];

        SupportedMultiComp = {'none','fdr','FWERperm','bonferroni','clusterPerm'};
        
        FWERperm_SupportedMethods = {'BMfast','regresfast'};
        clusterPerm_SupportedMethods = {'regresfast'};


        %Default abbrevions. The first cell displayed in the GUI; the
        %second cell is the parameter for LESYMAP, the third is the a
        %parameter for NiiStat (if relevant)


        vlsm_continous = 1; % VLSM contnious (1) or binary (0) data
        behavior_file = '';
        behavior_file_path = '.\data'; %Default path to behavior file
        save_pwd = '';
        behavior_tab = [];
        save_side_column = [];
        save_subject_column = [];
        dummy_fig = [];


    end
    
    properties (Access = public)
        
        %FWER control options:
        %1st cell: GUI display
        %2nd cell: LESYMAP parameter, '' if not supported
        %3rd cell: NiiStat parameter, NaN if not supported
        %4th cell: supported statistical tests for LESYMAP
        %5th cell: supported statistical test for NiiStat
        DefaultNPerm = 1000;
        fwer = struct('bonf',{'Bonferroni','bonferroni','0'}, ...
                      'fdr',{'FDR','fdr','-1'}, ...
                      'perm',{'Permutations','FWERperm','1000'}, ...
                      'cperm',{'Cluster perm.','clusterPerm',''})
        perm_and_cluster = {'Permutations', 'FDR', 'Bonferroni', 'Cluster perm.'};
        perm_no_cluster = {'Permutations', 'FDR', 'Bonferroni'};
        no_perm = {'FDR', 'Bonferroni'};


        %Statistical test
        %1st cell: GUI display
        %2nd cell: LESYMAP parameter, '' if not supported
        %3rd cell: NiiStat parameter, NaN if not supported
        %4th cell: supported statistical tests for LESYMAP
        %5th cell: supported statistical test for NiiStat       
        stest = struct('t',{'t-test / linear reg.','regresfast','1'},...
                       'BM',{'Brunner-Munzel','BMfast','NaN'},...
                       'chisq',{'Chi-squared','chisq','NaN'},...
                       'Lieb',{'Liebermeister','','1'});
                   
        DefaultContinousTests = {'t-test / linear reg.','Brunner-Munzel'};
        DefaultBinaryTests = {'Chi-squared','Liebermeister'};
      
    end
    
    methods (Access = private)

        function before_dialog(app)
            %Create a dummy figure
            app.dummy_fig = figure('Visible', 'off');
            
        end

        function after_dialog(app)
            % Delete the dummy figure
            delete(app.dummy_fig);
            
            % Bring the GUI window back to focus
            figure(app.BrainLabFigure)
        end

        function update_behavior_gui(app, option)

            if strcmp(option,'side')
                %1. If side has changed, check whether it is valid:
                side_column = try_find_side_column (app, app.DropDown_SideColumn.Value);
                app.DropDown_SideColumn.Value = side_column;
    
                %1. if side is not none, check if it is valudenable the side selection listbox
                %and remove it for columns that are avaiable
                if ~strcmp(side_column,'None') 
                    app.ListBox_SideSelect.Enable = 'on';
                    app.DropDown_SubjectColumn.Items = remove_item(app.DropDown_SubjectColumn.Items, side_column);
                    app.ListBox_Available.Items = remove_item(app.ListBox_Available.Items, side_column);
                    app.ListBox_Analyze.Items = remove_item(app.ListBox_Analyze.Items, side_column);


                else
                    app.ListBox_SideSelect.Enable = 'off';
                    
                end
                
                %return previous value to the available columns add it back into available columns to select:
                if (~ismember(app.save_side_column,app.ListBox_Available.Items)) && (~strcmp(app.save_side_column,'None'))
                        app.ListBox_Available.Items = [{app.save_side_column}, app.ListBox_Available.Items];
                        app.DropDown_SubjectColumn.Items = [{app.save_side_column}, app.DropDown_SubjectColumn.Items];
                end
                if iscell(side_column)
                    app.save_side_column = side_column{1};
                else
                    app.save_side_column = side_column;
                end
            end

            %2. If pcode has changed
            if strcmp(option,'subject')
                subject_column = app.DropDown_SubjectColumn.Value;
                %remove the selected column from available columns and columns
                %to analyze:
                app.ListBox_Available.Items = remove_item(app.ListBox_Available.Items, subject_column);
                app.ListBox_Analyze.Items = remove_item(app.ListBox_Analyze.Items, subject_column);
                app.DropDown_SideColumn.Items = remove_item(app.DropDown_SideColumn.Items, subject_column);
    
                %add back the previous column to available columns:
                app.ListBox_Available.Items = [{app.save_subject_column}, app.ListBox_Available.Items];
                app.DropDown_SideColumn.Items = [{app.save_subject_column}, app.DropDown_SideColumn.Items];
                
                %update the save_subject:
               if iscell(subject_column)
                    app.save_subject_column = subject_column{1};
                else
                    app.save_subject_column = subject_column;
                end
                
            end

            %Drop duplicates in all listboxes and dropdown lists:

            app.ListBox_Available.Items = get_unique_elements(app.ListBox_Available.Items);
            app.ListBox_Analyze.Items = get_unique_elements(app.ListBox_Analyze.Items);
            app.DropDown_SubjectColumn.Items = get_unique_elements(app.DropDown_SubjectColumn.Items);    
            app.DropDown_SideColumn.Items = get_unique_elements(app.DropDown_SideColumn.Items);  
     
        end

        function side_column = try_find_side_column (app, varargin)
            side_column = 'None'; %default - no side column
            if isempty(varargin)
                columns = app.behavior_tab.Properties.VariableNames;
            else
                columns = {varargin{1}};
            end
          
            for column_name=columns
                if ~strcmp(column_name,'None')
                    unique_vals = unique(app.behavior_tab{:,column_name});
                    if ismember('L',unique_vals) && ismember('R',unique_vals)
                        side_column = column_name;
                    end
                end
            end
        end


        function init_behavior_gui(app)
            if isfile(app.behavior_file)
                %load columns list
                app.behavior_tab = readtable (app.behavior_file);   
                columns = app.behavior_tab.Properties.VariableNames;
              
                %update DropDown_SubjectColumn
                app.DropDown_SubjectColumn.Items = columns;
                if ismember(columns,app.DefaultSubjectColumn)
                   app.DropDown_SubjectColumn.Value = app.DefaultSideColumn;
                else
                    app.DropDown_SubjectColumn.Value = columns{1};
                end
                app.save_subject_column = app.DropDown_SubjectColumn.Value;
                                
                %update DropDown_SideColumn
                app.DropDown_SideColumn.Items = [{'None'}, columns];

                %search Side autmoatically:
                app.DropDown_SideColumn.Value = try_find_side_column(app);
                if ~strcmp(app.DropDown_SideColumn.Value,'None')
                    app.ListBox_SideSelect.Enable = 'on';
                else
                    app.ListBox_SideSelect.Enable = 'off';
                end

                app.save_side_column = app.DropDown_SideColumn.Value;

                %update listbox_available behaviors
                elementsToRemove = {app.DropDown_SideColumn.Value, app.DropDown_SubjectColumn.Value};
                available_columns = remove_item(columns, elementsToRemove);
                app.ListBox_Available.Items = available_columns;

                %update listbox_analyze beahviors
                app.ListBox_Analyze.Items = {};
                app.ListBox_Analyze.Value = {};


            end
        end
        
        function [args, error] = check_and_get_behavior_args (app, action)
               
            args = struct();
            error = false;

            %common checks for all actions
            %1. behavior file
            if isempty(app.behavior_file) || ~isfile(app.behavior_file)
                error = true;
                msgbox(sprintf('Valid behavior file  is required(%s is missing )',app.behavior_file))
                return
            end
            args.behavior_fn = app.behavior_file;
            
            %2. subject column
            args.subject_column = app.DropDown_SubjectColumn.Value;
            if strcmp(args.subject_column,'None')
                error = true;
                msgbox('Subject column must be selected. Provide valid behavior file and press the LOAD button.')
                return
            end

            %3. lesions folder
            args.folder = app.EditField_lesions_folder.Value;
            if ~isfolder(args.folder)
                error = true;
                msgbox(sprintf('Lesions folder "%s" was not found.',folder))
                return
            end
            
            %4. side column
            args.side_column = app.DropDown_SideColumn.Value;


            %arguments which are specific for certain actions:
            switch action
                case 'overlay'

                    %5.1. output folder
                    args.outdir = app.EditField_output_folder.Value;
                    if ~isfolder(args.outdir)
                        %folder does not exist, try to create it:
                        try
                            mkdir(args.outdir)
                        catch
                            error = true;
                            msgbox(sprintf('Unable to create %s for some reason, make sure path is correct.',args.outdir))
                            return
                        end
                    end
                        
                    %5.2 which side
                    which_side_val = app.ListBox_SideSelect.Value;
                    if length(which_side_val) > 1
                        args.which_side = 'NULL';
                    else
                        args.which_side = which_side_val;
                    end

                    %5.3. interim
                    args.interim = app.CheckBox_overlay_interim_images.Value;
                    
                    %5.4 threshold
                    threshold_val = str2double(app.EditField_Threshold.Value);
                    if threshold_val < 0
                        args.threshold = 0;
                    elseif threshold_val > 1
                            args.threshold = 1;
                    else
                        args.threshold = threshold_val;
                    end

                    %5.5. fn_out
                    args.fn_out = [args.outdir,'\overlay'];

                case 'flip'
                    %((behavior_fnV, folderV, subject_column = 'pcode'V, side_column = 'Side'V, dest_folder=out_dirV, fip_to = 'L')
                    flip_to_val = app.DropDown_FlipDir.Value;
                    if strcmp(flip_to_val,'R->L')
                        args.flip_to = 'L';
                    else
                        args.flip_to = 'R';
                    end

                    args.dest_folder = [app.EditField_lesions_folder.Value, '_flipped'];

                    if ~isfolder(args.dest_folder) 
                        try 
                            mkdir(args.dest_folder)
                        catch
                            error = true;
                            msgbox (sprintf('Could not create folder for flipped lesion images: %s',arg.dest_folder))
                            return
                        end
                    end


            end


        end
        
        function new_fn = check_n_convert_hdr(app,fn)
            
            pos = findstr(fn,'.nii.gz');
            if isempty(pos)
                pos = findstr(fn,'.nii');
                if isempty(pos)
                    pos = findstr(fn,'.hdr');
                    if isempty(pos)
                        ext = '';
                    else
                        ext = '.hdr';
                    end
                else
                    ext = '.nii';
                end
            else
                ext = '.nii.gz';
            end
            
            
            if strcmpi(ext,'.hdr')
                full_fn = [app.EditField_working_dir.Value,'\',fn];
                set_status (app,1,sprintf('Converting HDR file "%s" to NIfTI...',fn))
                convert_analyze2nifti (full_fn)
                set_status(app,0)
                pos = findstr(fn,'.hdr');              
                new_fn = [fn(1:pos-1), '.nii.gz'];
            else
                new_fn = fn;
            end
                
        end
        
        function  set_status(app, status, varargin)
            if nargin > 2
                text = varargin{1};
            else
                text = 'Ready';
            end
            app.StatusLabel.Text = text;
            if status
                app.StatusLamp.Color = 'r';                
            else
                app.StatusLamp.Color = 'g';                
            end
            drawnow;
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_status(app,1,'Initializing...')
                    
            % Get the full path of the current working directory
            currentFolder = pwd;
        
            % Get the parent directory
            fullpath_parentFolder = fileparts(currentFolder);
            [~, parentFolder, ~] = fileparts(fullpath_parentFolder);
            
            % save current working directory to restore it after closing
            % the app:
            app.save_pwd = currentFolder;
                
            % Check if the parent directory matches the target
            if strcmp(parentFolder, 'brainlab')
                % Change to the parent directory
                cd('..');
                
                fprintf('Changed to parent directory: %s\n', parentFolder);
            end
            %invoke brainlab docker-container:
            invoke_container;
            
            %Load the list of atlases and templates:
            ReloadButtonPushed(app);
            
            %VLSM init:
            BehaviorScoreTypeDropDownValueChanged(app)
            StatisticalTestDropDownValueChanged(app)
            set_status(app,0)

            
        end

        % Button pushed function: Button_working_dir
        function Button_working_dirPushed(app, event)
            before_dialog(app);
            dirname = uigetdir(app.EditField_working_dir.Value,"Select working directory");
            after_diaglog(app)
            if (dirname ~= 0)
                app.EditField_working_dir.Value = dirname;  
            end
        end

        % Button pushed function: Button_brain_file
        function Button_brain_filePushed(app, event)
            before_dialog(app);
            [fn,~,indx] = uigetfile(app.SupportedFileFormats,'Select brain image file',app.EditField_working_dir.Value');
            after_dialog(app);
            if (fn ~= 0 )
                app.EditField_brain_file.Value = fn;        
            end
        end

        % Button pushed function: Button_lesion_file
        function Button_lesion_filePushed(app, event)
            before_dialog(app);
            fn = uigetfile(app.SupportedFileFormats,'Select lesion image file',app.EditField_working_dir.Value');
            after_dialog(app);
            if (fn ~= 0)
                app.EditField_lesion_file.Value= fn;        
            end
        end

        % Value changed function: EditField_working_dir
        function EditField_working_dirValueChanged(app, event)
            value = app.EditField_working_dir.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_working_dir.Value = app.DefaultWorkingDir;
            end
        end

        % Value changed function: EditField_brain_file
        function EditField_brain_fileValueChanged(app, event)
            value = app.EditField_brain_file.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_brain_file.Value = app.DefaultBrainFilename;
            end
        end

        % Value changed function: EditField_lesion_file
        function EditField_lesion_fileValueChanged(app, event)
            value = app.EditField_lesion_file.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_lesion_file.Value = app.DefaultLesionFilename;
            end
        end

        % Value changed function: CheckBox_heal
        function CheckBox_healValueChanged(app, event)
            value = app.CheckBox_heal.Value;
            if value
                app.EditField_smooth_factor.Enable = true;
            else
                app.EditField_smooth_factor.Enable = false;
            end
            
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            
           %Retrieve data from GUI (batch-mode insensitive):
           heal_op = app.CheckBox_heal.Value;
           smooth_op = app.EditField_smooth_factor.Value;
           atlas_name = app.ListBox_atlas.Value;
           is_ct = app.ct.Value;

           %pre-realignment option
           switch lower(app.DropDown_selectAlignMethod.Value)
               case 'rigid (recommended)'
                   align_op = 'Rigid';
               case 'affine'
                   align_op = 'Affine';
               case 'none (not recommended)'
                   align_op = 'none';
               otherwise
                   align_op = 'Rigid';
           end
           
           %registration method:
            switch lower(app.DropDown_selectRegAlg.Value)
                case 'syn (recommended)'
                   reg_method = 'SyN';
               case 'affine'
                   reg_method= 'Affine';
                case 'rigid'
                   reg_method = 'Rigid';
               otherwise
                   reg_method = 'SyN';
            end  

            interim_output = app.CheckBox_interim_output.Value;

           %temp_op: 
           switch lower(app.ListBox_template.Value)
                case 'atlas-specific for mri and scct for ct'
                    if is_ct
                        temp_op = 'scct';
                    else
                        temp_op = 'atlas';
                    end
               case 'atlas-specific'
                    temp_op = 'atlas';
                otherwise
                    temp_op = 'atlas';
           end         
         
            
            % Check if both brain and lesion images are valid
             wd = app.EditField_working_dir.Value;
            x = [wd,'\',app.EditField_brain_file.Value];
            les = [wd,'\',app.EditField_lesion_file.Value];
            if (~isfile(x) || ~(isfile(les)))
                if (~isfile(x))
                    msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                else
                    msg = errordlg(sprintf('Lesion image "%s" was not found',les));                    
                end
           else
                                    
                %If either brain or lesion are not in nii/nii.gz file format
                %(one which oro.nifti::readNIftI() can read...) - convert
                fn = app.EditField_brain_file.Value;
                new_fn = check_n_convert_hdr(app,fn);
                if ~strcmpi(fn,new_fn)
                    app.EditField_brain_file.Value = new_fn;
                end
                                   
                
                fn = app.EditField_lesion_file.Value;
                new_fn = check_n_convert_hdr(app,fn);
                if ~strcmpi(fn,new_fn)
                    app.EditField_lesion_file.Value = new_fn;
                end
                
                %Retrieve data from GUI:
                p_folder = app.EditField_working_dir.Value;
                x_fname = app.EditField_brain_file.Value;
                lesion_fname = app.EditField_lesion_file.Value;


                file_format = 'nii';            
                
                %Execute WSL-R registration engine:
                %function normalize_brain (patient_folder, x_fname, lesion_fname, file_format, atlas_name, is_ct, temp_op, heal_op, smooth_op)
                set_status(app,1,'Normalizing brain & lesion...') 
                normalize_brain (p_folder,...
                                 x_fname,...
                                 lesion_fname,...
                                 file_format,...
                                 atlas_name,...
                                 is_ct, ...
                                 temp_op, ...
                                 heal_op, ...
                                 smooth_op, ...
                                 align_op, ...
                                 reg_method, ...
                                 interim_output)
                set_status(app,0)
            end %
            
        end

        % Close request function: BrainLabFigure
        function BrainLabFigureCloseRequest(app, event)

            close_container;
            
            %restore previous working dir
            try
                cd(app.save_pwd);
            catch
            end

            delete(app)
            %diary off
        end

        % Button pushed function: ConvertMEDxFilesButton
        function ConvertMEDxFilesButtonPushed(app, event)

                wd = app.EditField_working_dir.Value;            
                %Check if both brain and lesion images are valid
                x = [wd,'\',app.EditField_brain_file.Value];
                if isfile(x)
                    fn = app.EditField_brain_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_brain_file.Value = new_fn;
                    end 
                end
                              
                les = [wd,'\',app.EditField_lesion_file.Value];
                if isfile(les)
                   fn = app.EditField_lesion_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_lesion_file.Value = new_fn;
                    end                  
                end
                
                if (~isfile(x)) && ~(isfile(les))
                    if (~isfile(x))
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                    else
                        msg = errordlg(sprintf('Lesion image "%s" was not found',les));                    
                    end
                end       
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            t = readtable([app.TemplatesAtlasesDir, '\atlas_list.csv'],'Delimiter',',','PreserveVariableNames',true);
            app.ListBox_atlas.Items = t.("atlas.name");
            if isempty(app.ListBox_atlas.Value)
                app.ListBox_atlas.Value = app.ListBox_atlas.Items{1};
            end
        end

        % Callback function
        function EditField_batchtableValueChanged(app, event)
            value = app.EditField_batchtable.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_brain_file.Value = app.DefaultBatchTableFilename;
            end           
        end

        % Callback function
        function Button_batchtablePushed(app, event)
            before_dialog(app);
            [fn,~,indx] = uigetfile(app.SupportedBatchTableFormats,'Select batch table file',app.EditField_working_dir.Value);
            after_dialog(app);
            if (fn ~= 0)
                app.EditField_batchtable.Value = fn;        
            end
        end

        % Callback function
        function BatchCheckBoxValueChanged(app, event)
                    
        end

        % Value changed function: EditField_lesions_folder
        function EditField_lesions_folderValueChanged(app, event)
          
            value = app.EditField_lesions_folder.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_lesions_folder.Value = app.DefaultWorkingDir;
            end
        end

        % Button pushed function: Button_lesions_folder
        function Button_lesions_folderPushed(app, event)
            before_dialog(app);    
            dirname = uigetdir(app.EditField_lesions_folder.Value,"Select lesions directory");
            after_dialog(app);
            if (dirname ~= 0)
                app.EditField_lesions_folder.Value = dirname;  
            end            
        end

        % Value changed function: EditField_behavior_file
        function EditField_behavior_fileValueChanged(app, event)
           
            invalid = false;
            value = app.EditField_behavior_file.Value;
            fn = fullfile(app.EditField_working_dir, value);
            if isfile(value) 
                app.behavior_file = fullfile(value);
            elseif isfile(fn)
                app.behavior_file = fn;
            else
                invalid = true;
            end

            if invalid
                msgbox('Behavior file was not found.');
                app.EditField_behavior_file.FontColor = 'r';
                app.LoadButton.Enable = 'off';
            else
                update_behavior_gui(app);               
                set_status(app,0,'Behavior file found.');
                app.EditField_behavior_file.FontColor = 'k';
                app.LoadButton.Enable = 'on';
            end
 
         
        end

        % Button pushed function: Button_behavior_file
        function Button_behavior_filePushed(app, event)
            before_dialog(app); 
            [fn,path] = uigetfile(app.SupportedBatchTableFormats,'Select batch table file',app.EditField_lesions_folder.Value);
            after_dialog(app);    
            if (fn ~= 0)
                app.EditField_behavior_file.Value = fn; 
                if isfile(fullfile(path,fn)) 
                    app.behavior_file = fullfile(path,fn);
                    app.EditField_behavior_file.FontColor = 'k';
                    app.LoadButton.Enable = 'on';                    
                    set_status(app,0,'Behavior file found.');
                else
                    app.EditField_behavior_file.FontColor = 'r';
                    app.LoadButton.Enable = 'off';
                    set_status(app,1,'Behavior file was not found.');
                end
            end           
        end

        % Callback function: not associated with a component
        function BehaviorScoreTypeDropDownValueChanged(app, event)
            value = app.BehaviorScoreTypeDropDown.Value;
            if strcmpi(value,'continous')
                app.vlsm_continous = 1;
            else
                app.vlsm_continous = 0;
            end
            
            if app.vlsm_continous
                app.DropDown_StatisticalMethod.Items = app.DefaultContinousTests;
            else
               app.DropDown_StatisticalMethod.Items = app.DefaultBinaryTests; 
            end
            app.DropDown_StatisticalMethod.Value = app.DropDown_StatisticalMethod.Items{1};
            app.StatisticalTestDropDownValueChanged(app)
        end

        % Callback function: not associated with a component
        function StatisticalTestDropDownValueChanged(app, event)
            value = app.DropDown_StatisticalMethod.Value;
                     
            %Change Multiple Comparisons and Software Use dropbox accordingly
  
            switch lower(value)
                case lower(app.stest(1).t)
                    app.DropDown_MultipleComp.Items = app.perm_and_cluster;
                    app.UseDropDown.Items = {'LESYMAP','NiiStat'};
                case lower(app.stest(1).BM)
                    app.DropDown_MultipleComp.Items = app.perm_no_cluster;
                    app.UseDropDown.Items = {'LESYMAP'};
                case lower(app.stest(1).chisq)
                    app.DropDown_MultipleComp.Items = app.no_perm;
                    app.UseDropDown.Items = {'LESYMAP'};                   
                case lower(app.stest(1).Lieb)
                    app.DropDown_MultipleComp.Items = app.perm_no_cluster;
                    app.UseDropDown.Items = {'NiiStat'};
                    
            end
            app.DropDown_MultipleComp.Value = app.DropDown_MultipleComp.Items{1};
            app.UseDropDown.Value = app.UseDropDown.Items{1};
            
        end

        % Callback function: not associated with a component
        function CorrectionforMultipleComparisonsDropDownValueChanged(app, event)
            value = app.DropDown_MultipleComp.Value;
            if strcmpi(value,app.fwer(1).cperm)
                app.UseDropDown.Items = {'LESYMAP'};
                app.UseDropDown.Value = 'LESYMAP';
            else
                value2 = app.DropDown_StatisticalMethod.Value;
                use_list = {'LESYMAP','NiiStat'};
                switch value2
                    case app.stest(1).t, use_list = {'LESYMAP','NiiStat'};
                    case app.stest(1).BM, use_list = {'LESYMAP'};
                    case app.stest(1).chisq, use_list = {'LESYMAP'};
                    case app.stest(1).Lieb, use_list = {'NiiStat'};
                          
                end
                app.UseDropDown.Items = use_list;
                app.UseDropDown.Value = use_list{1};
            end
        end

        % Callback function: not associated with a component
        function ExecuteVLSMButtonPushed(app, event)
            %Retrieve information from GUI
         
            img_path = app.EditField_lesions_folder.Value;
            analysis_tab_file = [img_path ,'\',app.EditField_behavior_file.Value];
            out_dir = app.EditField_output_folder.Value;
            
            software = app.UseDropDown.Value;
            if strcmpi(software,'lesymap')
                indx = 2;
            else
                indx = 3;
            end
            
            gui_stest = app.DropDown_StatisticalMethod.Value;
            meth = '';
            switch gui_stest
                case app.stest(1).t, meth = app.stest(indx).t;
                case app.stest(1).BM, meth = app.stest(indx).BM;
                case app.stest(1).chisq, meth = app.stest(indx).chisq;
                case app.stest(1).Lieb, meth = app.stest(indx).Lieb;
            end


            correct_lesion_size = app.CheckBox_VolumeCorrection.Value;
            
            fwer_gui = app.DropDown_MultipleComp.Value;
            fwer = '';
            switch fwer_gui
                case app.fwer(1).bonf, fwer = app.fwer(indx).bonf;
                case app.fwer(1).fdr, fwer = app.fwer(indx).fdr;
                case app.fwer(1).perm, fwer = app.fwer(indx).perm;                         
                case app.fwer(1).cperm, fwer = app.fwer(indx).cperm;
            end
            
            if indx == 3
               if strcmp(fwer_gui,app.fwer(1).perm)
                  fwer_niistat = app.EditField_nperm.Value;
               else
                  fwer_niistat = str2double(fwer); 
               end
            end
         
            
            nperm = app.EditField_nperm.Value;
            alpha = app.EditField_Significance.Value;
            min_overlap = app.EditField_minSubj.Value;
            peak_voxel = app.EditField_v.Value;
            
            behave_dir = app.BehavioralScoreDropDown.Value;
            
            if indx == 2
                %LESYMAP
                if correct_lesion_size, LesionSize = 'behavior'; else, LesionSize = 'none'; end
                if strcmpi(behave_dir,'performance'), flip_sign = 0; else, flip_sign = 1; end
                set_status(app,1,'Running Voxel-based Lesion Symptom mapping using LESYMAP package...') 
                vlsm_lesymap(img_path, analysis_tab_file, out_dir, meth, LesionSize, fwer, alpha, flip_sign, min_overlap, nperm, peak_voxel)
                set_status(app,0)
            else %NiiStat
                
                vlsm_niistat(img_path, analysis_tab_file, out_dir, fwer_niistat, alpha, min_overlap, correct_lesion_size);
            end
            
           
        end

        % Button pushed function: Button_output_folder
        function Button_output_folderPushed(app, event)
            before_dialog(app);    
            dirname = uigetdir(app.EditField_output_folder.Value,"Select output folder");
            after_dialog(app);
            if (dirname ~= 0)
                app.EditField_output_folder.Value = dirname;  
            end                       
        end

        % Callback function
        function EditField_output_folderValueChanged(app, event)
           value = app.EditField_output_folder.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_output_folder.Value = app.DefaultWorkingDir;
            end           
        end

        % Button pushed function: Transform8bitButton
        function Transform8bitButtonPushed(app, event)
              wd = app.EditField_working_dir.Value;
              fn = app.EditField_brain_file.Value;
              x = [wd ,'\', fn];
                if (~isfile(x))
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                else    
                        set_status(app,1,'Transforming 8-bit brain image...')
                        new_fn = transform_8bit_files(wd,fn);
                        if ~isempty(new_fn)
                            app.EditField_brain_file.Value = new_fn;
                        end
                        set_status(app,0)
                end
        end

        % Button pushed function: DeskullButton
        function DeskullButtonPushed(app, event)
              %Check if both brain alesion image is valid
                wd = app.EditField_working_dir.Value;
                x = [wd,'\',app.EditField_brain_file.Value];
                if (~isfile(x))             
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                                       
                else                                    
                    %If either brain or lesion are not in nii/nii.gz file format
                    %(one which oro.nifti::readNIftI() can read...) - convert
                    fn = app.EditField_brain_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_brain_file.Value = new_fn;
                    end
                    
                    %Retrieve data from GUI:
                    p_folder = app.EditField_working_dir.Value;
                    x_fname = app.EditField_brain_file.Value;
                    is_ct = app.ct.Value;
                    fraq = str2double(app.DeskullFactorEditField.Value);
                    if ((fraq < 0) || (fraq > 1))
                        fraq = 0.5;                        
                    end
                              
                    
                    %deskull using brainlab container:                   
                    set_status(app,1,'Performing brain extraction (deskulling)...')
                    newfile = deskull (p_folder, x_fname, is_ct, fraq);   
                    if ~isempty(newfile)                        
                       app.EditField_brain_file.Value = newfile;
                    end
                    set_status(app,0)
                end %
        end

        % Callback function: not associated with a component
        function Button_BehaviorFilePushed(app, event)

            [fn,path] = uigetfile(app.SupportedBatchTableFormats,'Select behavior table file',app.EditField_BehaviorFile.Value);

            if (fn ~= 0)
                app.EditField_BehaviorFile.Value = fn;  
                app.path_to_BehaviorFile = path;
            end

        end

        % Callback function: not associated with a component
        function EditField_BehaviorFileValueChanged(app, event)
            value = app.EditField_BehaviorFile.Value;
            if ~isfile(value) %checks if file name is valid
                behavior_filename = [app.path_to_BehaviorFile,'\',value];
                if ~isfile(behavior_filename)
                    app.EditField_BehaviorFile.Value = app.DefaultBehaviorFilename;
                end
            else
                app.path_to_BehaviorFile = '';
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            init_behavior_gui(app);
        end

        % Value changed function: DropDown_SideColumn
        function DropDown_SideColumnValueChanged(app, event)
            %value = app.DropDown_SideColumn.Value;
            update_behavior_gui(app,'side');
        end

        % Value changed function: DropDown_SubjectColumn
        function DropDown_SubjectColumnValueChanged(app, event)
            %value = app.DropDown_SubjectColumn.Value;
            update_behavior_gui(app,'subject');
        end

        % Button pushed function: Button_AddBehavior
        function Button_AddBehaviorPushed(app, event)
            %get the marked values in the available listbox
            marked_behaviors = app.ListBox_Available.Value;
            
            %get the existing values in the analyze listbox
            current_behaviors = app.ListBox_Analyze.Items;

            %find the marked behaviors that are not current
            marked_not_current = setdiff(marked_behaviors, current_behaviors);

            %add the marked behaviors to the Analyze listbox:
            app.ListBox_Analyze.Items = [marked_not_current, app.ListBox_Analyze.Items];

            
        end

        % Button pushed function: Button_RemoveBehavior
        function Button_RemoveBehaviorPushed(app, event)
            %get the marked values in the analyze listbox:
            marked_behaviors = app.ListBox_Analyze.Value;

            %get all of the items in the analyze listbox:
            analyze_behaviors = app.ListBox_Analyze.Items;

            %remove the marked behaviors
            app.ListBox_Analyze.Items = remove_item(analyze_behaviors, marked_behaviors);
        end

        % Button pushed function: CreateOverlayImageButton
        function CreateOverlayImageButtonPushed(app, event)
            
            [args, error] = check_and_get_behavior_args(app,'overlay');
            if error
                return
            end
            set_status(app,1,'Overlaying figures...Please wait...')
            overlay (args.behavior_fn, ...
                     args.folder,...
                     args.fn_out,...
                     args.subject_column,...
                     args.interim,...
                     args.side_column,...
                     args.which_side,...
                     args.threshold)
            set_status(app,0,'Overlayed figures were created.')
        end

        % Value changed function: EditField_Threshold
        function EditField_ThresholdValueChanged(app, event)
            value = str2double(app.EditField_Threshold.Value);
            if value < 0 
                value = 0;
            elseif value > 1
                value = 1;
            end
            app.EditField_Threshold.Value = num2str(value);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            %***continue here: build matlab wrapper to flip.lesions() which
            %was checked and is working!
            
            [args, error] = check_and_get_behavior_args(app,'flip');
            if error
                return
            end
            %(behavior_fn, folder, subject_column = 'pcode', side_column = 'Side', dest_folder, fip_to = 'L')
            set_status(app,1,'Flipping...Please wait...')
            flip_lesions (args.behavior_fn, ...
                     args.folder,...
                     args.subject_column,...
                     args.side_column,...
                     args.dest_folder,... 
                     args.flip_to)
            if isfolder(args.dest_folder)
                app.EditField_lesions_folder.Value = args.dest_folder;
            end
            set_status(app,0,'Flipped figures were created.')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrainLabFigure = uifigure('Visible', 'off');
            app.BrainLabFigure.Name = 'BrainLab: Brain Image Analysis Tool';
            app.BrainLabFigure.CloseRequestFcn = createCallbackFcn(app, @BrainLabFigureCloseRequest, true);
            app.BrainLabBrainImageAnalysisToolLabel = uilabel(app.BrainLabFigure);
            app.BrainLabBrainImageAnalysisToolLabel.Text = 'BrainLab: Brain Image Analysis Tool';
            app.Panel_status = uipanel(app.BrainLabFigure);
            app.StatusLabel = uilabel(app.Panel_status);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel_status);
            app.TabGroup = uitabgroup(app.BrainLabFigure);
            app.SingleSubjectTab = uitab(app.TabGroup);
            app.SingleSubjectTab.Title = 'Single Subject';
            app.NormalizationPanel = uipanel(app.SingleSubjectTab);
            app.NormalizationPanel.Title = 'Normalization';
            app.Panel_heal = uipanel(app.NormalizationPanel);
            app.CheckBox_heal = uicheckbox(app.Panel_heal);
            app.CheckBox_heal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_healValueChanged, true);
            app.CheckBox_heal.Text = 'Enantiomorphic';
            app.CheckBox_heal.Value = true;
            app.SmoothFactorLabel = uilabel(app.Panel_heal);
            app.SmoothFactorLabel.Text = 'Smooth Factor:';
            app.EditField_smooth_factor = uieditfield(app.Panel_heal, 'numeric');
            app.EditField_smooth_factor.Limits = [0 6];
            app.EditField_smooth_factor.Value = 1.5;
            app.DropDown_selectRegAlg = uidropdown(app.Panel_heal);
            app.DropDown_selectRegAlg.Items = {'Rigid', 'Affine', 'SyN (recommended)'};
            app.DropDown_selectRegAlg.Value = 'SyN (recommended)';
            app.RegistrationMethodLabel = uilabel(app.Panel_heal);
            app.RegistrationMethodLabel.Text = 'Registration Method:';
            app.DropDown_selectAlignMethod = uidropdown(app.Panel_heal);
            app.DropDown_selectAlignMethod.Items = {'Rigid (recommended)', 'Affine', 'None (not recommended)'};
            app.DropDown_selectAlignMethod.Value = 'Rigid (recommended)';
            app.AlignmentMethodLabel = uilabel(app.Panel_heal);
            app.AlignmentMethodLabel.Text = 'Alignment Method:';
            app.CheckBox_interim_output = uicheckbox(app.Panel_heal);
            app.CheckBox_interim_output.Text = 'save interim results';
            app.CheckBox_interim_output.Value = true;
            app.Panel_template = uipanel(app.NormalizationPanel);
            app.TemplateListBoxLabel = uilabel(app.Panel_template);
            app.TemplateListBoxLabel.Text = 'Template:';
            app.ListBox_template = uilistbox(app.Panel_template);
            app.ListBox_template.Items = {'Atlas-Specific', 'Atlas-specific for MRI and scct for CT'};
            app.ListBox_template.Value = 'Atlas-Specific';
            app.Panel_atlas = uipanel(app.NormalizationPanel);
            app.AtlasListBoxLabel = uilabel(app.Panel_atlas);
            app.AtlasListBoxLabel.Text = 'Atlas:';
            app.ListBox_atlas = uilistbox(app.Panel_atlas);
            app.ListBox_atlas.Items = {};
            app.ListBox_atlas.Value = {};
            app.ReloadButton = uibutton(app.Panel_atlas, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.NormalizeButton = uibutton(app.Panel_atlas, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.LoadDataPanel = uipanel(app.SingleSubjectTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.WorkingDirectoryLabel = uilabel(app.LoadDataPanel);
            app.WorkingDirectoryLabel.Text = {'Working Directory:'; ''};
            app.Button_working_dir = uibutton(app.LoadDataPanel, 'push');
            app.Button_working_dir.ButtonPushedFcn = createCallbackFcn(app, @Button_working_dirPushed, true);
            app.Button_working_dir.Text = 'Browse...';
            app.EditField_working_dir = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_working_dir.ValueChangedFcn = createCallbackFcn(app, @EditField_working_dirValueChanged, true);
            app.EditField_working_dir.Value = 'C:\Scripts\Brain-Lab\Repo\brainlab\data\';
            app.BrainImageLabel = uilabel(app.LoadDataPanel);
            app.BrainImageLabel.Text = {'Brain Image:'; ''};
            app.Button_brain_file = uibutton(app.LoadDataPanel, 'push');
            app.Button_brain_file.ButtonPushedFcn = createCallbackFcn(app, @Button_brain_filePushed, true);
            app.Button_brain_file.Text = 'Browse...';
            app.EditField_brain_file = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_brain_file.ValueChangedFcn = createCallbackFcn(app, @EditField_brain_fileValueChanged, true);
            app.EditField_brain_file.Value = '*.nii.gz';
            app.LesionimageLabel = uilabel(app.LoadDataPanel);
            app.LesionimageLabel.Text = 'Lesion image:';
            app.Button_lesion_file = uibutton(app.LoadDataPanel, 'push');
            app.Button_lesion_file.ButtonPushedFcn = createCallbackFcn(app, @Button_lesion_filePushed, true);
            app.Button_lesion_file.Text = 'Browse...';
            app.ConvertMEDxFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.ConvertMEDxFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertMEDxFilesButtonPushed, true);
            app.ConvertMEDxFilesButton.Text = 'Convert MEDx Files';
            app.Transform8bitButton = uibutton(app.LoadDataPanel, 'push');
            app.Transform8bitButton.ButtonPushedFcn = createCallbackFcn(app, @Transform8bitButtonPushed, true);
            app.Transform8bitButton.Text = 'Transform 8bit';
            app.EditField_lesion_file = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_lesion_file.ValueChangedFcn = createCallbackFcn(app, @EditField_lesion_fileValueChanged, true);
            app.EditField_lesion_file.Value = '*.nii.gz';
            app.BrainExtractionDeskullingPanel = uipanel(app.SingleSubjectTab);
            app.BrainExtractionDeskullingPanel.Title = 'Brain Extraction (Deskulling)';
            app.ButtonGroup_ct_or_mri = uibuttongroup(app.BrainExtractionDeskullingPanel);
            app.ct = uiradiobutton(app.ButtonGroup_ct_or_mri);
            app.ct.Text = 'CT';
            app.ct.Value = true;
            app.mri = uiradiobutton(app.ButtonGroup_ct_or_mri);
            app.mri.Text = 'MRI';
            app.DeskullFactorEditFieldLabel = uilabel(app.BrainExtractionDeskullingPanel);
            app.DeskullFactorEditFieldLabel.Text = 'Deskull Factor';
            app.DeskullFactorEditField = uieditfield(app.BrainExtractionDeskullingPanel, 'text');
            app.DeskullFactorEditField.Value = '0.4';
            app.DeskullButton = uibutton(app.BrainExtractionDeskullingPanel, 'push');
            app.DeskullButton.ButtonPushedFcn = createCallbackFcn(app, @DeskullButtonPushed, true);
            app.DeskullButton.Text = 'Deskull';
            app.GroupTab = uitab(app.TabGroup);
            app.GroupTab.Title = 'Group';
            app.SelectPanel = uipanel(app.GroupTab);
            app.SelectPanel.Title = 'Select';
            app.SubjectColumnDropDownLabel = uilabel(app.SelectPanel);
            app.SubjectColumnDropDownLabel.Text = 'Subject Column:';
            app.DropDown_SubjectColumn = uidropdown(app.SelectPanel);
            app.DropDown_SubjectColumn.Items = {'None'};
            app.DropDown_SubjectColumn.ValueChangedFcn = createCallbackFcn(app, @DropDown_SubjectColumnValueChanged, true);
            app.DropDown_SubjectColumn.Value = 'None';
            app.AvailableListBoxLabel = uilabel(app.SelectPanel);
            app.AvailableListBoxLabel.Text = 'Available';
            app.ListBox_Available = uilistbox(app.SelectPanel);
            app.ListBox_Available.Items = {};
            app.ListBox_Available.Multiselect = 'on';
            app.ListBox_Available.Value = {};
            app.Button_AddBehavior = uibutton(app.SelectPanel, 'push');
            app.Button_AddBehavior.ButtonPushedFcn = createCallbackFcn(app, @Button_AddBehaviorPushed, true);
            app.Button_AddBehavior.Text = '+';
            app.Button_RemoveBehavior = uibutton(app.SelectPanel, 'push');
            app.Button_RemoveBehavior.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveBehaviorPushed, true);
            app.Button_RemoveBehavior.Text = '-';
            app.AnalyzeListBoxLabel = uilabel(app.SelectPanel);
            app.AnalyzeListBoxLabel.Text = 'Analyze';
            app.ListBox_Analyze = uilistbox(app.SelectPanel);
            app.ListBox_Analyze.Items = {};
            app.ListBox_Analyze.Multiselect = 'on';
            app.ListBox_Analyze.Value = {};
            app.SideColumnDropDownLabel = uilabel(app.SelectPanel);
            app.SideColumnDropDownLabel.Text = 'Side Column:';
            app.DropDown_SideColumn = uidropdown(app.SelectPanel);
            app.DropDown_SideColumn.Items = {'None'};
            app.DropDown_SideColumn.ValueChangedFcn = createCallbackFcn(app, @DropDown_SideColumnValueChanged, true);
            app.DropDown_SideColumn.Value = 'None';
            app.ListBox_SideSelect = uilistbox(app.SelectPanel);
            app.ListBox_SideSelect.Items = {'L', 'R'};
            app.ListBox_SideSelect.Multiselect = 'on';
            app.ListBox_SideSelect.Enable = 'off';
            app.ListBox_SideSelect.Value = {'L'};
            app.LesionThresholdEditFieldLabel = uilabel(app.SelectPanel);
            app.LesionThresholdEditFieldLabel.Text = 'Lesion Threshold';
            app.EditField_Threshold = uieditfield(app.SelectPanel, 'text');
            app.EditField_Threshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ThresholdValueChanged, true);
            app.EditField_Threshold.Value = '0';
            app.LoadPanel = uipanel(app.GroupTab);
            app.LoadPanel.Title = 'Load';
            app.NormalizedLesionsFolderLabel = uilabel(app.LoadPanel);
            app.NormalizedLesionsFolderLabel.Text = 'Normalized Lesions Folder';
            app.Button_lesions_folder = uibutton(app.LoadPanel, 'push');
            app.Button_lesions_folder.ButtonPushedFcn = createCallbackFcn(app, @Button_lesions_folderPushed, true);
            app.Button_lesions_folder.Text = 'Browse...';
            app.EditField_lesions_folder = uieditfield(app.LoadPanel, 'text');
            app.EditField_lesions_folder.ValueChangedFcn = createCallbackFcn(app, @EditField_lesions_folderValueChanged, true);
            app.EditField_lesions_folder.Value = 'C:\Scripts\Brain-Lab\Repo\brainlab\data\';
            app.BehaviorFileLabel = uilabel(app.LoadPanel);
            app.BehaviorFileLabel.Text = 'Behavior File:';
            app.Button_behavior_file = uibutton(app.LoadPanel, 'push');
            app.Button_behavior_file.ButtonPushedFcn = createCallbackFcn(app, @Button_behavior_filePushed, true);
            app.Button_behavior_file.Text = 'Browse...';
            app.EditField_behavior_file = uieditfield(app.LoadPanel, 'text');
            app.EditField_behavior_file.ValueChangedFcn = createCallbackFcn(app, @EditField_behavior_fileValueChanged, true);
            app.EditField_behavior_file.Value = '*.csv';
            app.LoadButton = uibutton(app.LoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.DropDown_FlipDir = uidropdown(app.LoadPanel);
            app.DropDown_FlipDir.Items = {'R->L', 'L->R'};
            app.DropDown_FlipDir.Value = 'R->L';
            app.FlipButton = uibutton(app.LoadPanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.MaskFileoptionalLabel = uilabel(app.LoadPanel);
            app.MaskFileoptionalLabel.Text = 'Mask File (optional):';
            app.EditField_mask_file = uieditfield(app.LoadPanel, 'text');
            app.EditField_mask_file.Value = '*.csv';
            app.Button_behavior_file_2 = uibutton(app.LoadPanel, 'push');
            app.Button_behavior_file_2.Text = 'Browse...';
            app.OutputPanel = uipanel(app.GroupTab);
            app.OutputPanel.Title = 'Output';
            app.Button_output_folder = uibutton(app.OutputPanel, 'push');
            app.Button_output_folder.ButtonPushedFcn = createCallbackFcn(app, @Button_output_folderPushed, true);
            app.Button_output_folder.Text = 'Browse...';
            app.EditField_output_folder = uieditfield(app.OutputPanel, 'text');
            app.EditField_output_folder.Value = 'C:\Scripts\Brain-Lab\Repo\brainlab\data\';
            app.OutputFolderLabel = uilabel(app.OutputPanel);
            app.OutputFolderLabel.Text = 'Output Folder:';
            app.TabGroup2 = uitabgroup(app.GroupTab);
            app.DescriptiveTab = uitab(app.TabGroup2);
            app.DescriptiveTab.Title = 'Descriptive';
            app.CreateOverlayImageButton = uibutton(app.DescriptiveTab, 'push');
            app.CreateOverlayImageButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOverlayImageButtonPushed, true);
            app.CreateOverlayImageButton.Text = 'Create Overlay Image';
            app.CheckBox_overlay_interim_images = uicheckbox(app.DescriptiveTab);
            app.CheckBox_overlay_interim_images.Text = 'Include interim images';
            app.CreateROIReportButton = uibutton(app.DescriptiveTab, 'state');
            app.CreateROIReportButton.Text = 'Create ROI Report';
            app.VLSMTab = uitab(app.TabGroup2);
            app.VLSMTab.Title = 'VLSM';
            app.StatisticalMethodDropDownLabel = uilabel(app.VLSMTab);
            app.StatisticalMethodDropDownLabel.Text = 'Statistical Method';
            app.DropDown_StatisticalMethod = uidropdown(app.VLSMTab);
            app.DropDown_StatisticalMethod.Items = {'t-test', 'Brunner-Munzel', 'chi-square', 'Liebermeister'};
            app.DropDown_StatisticalMethod.Value = 't-test';
            app.CheckBox_FlipSign = uicheckbox(app.VLSMTab);
            app.CheckBox_FlipSign.Text = 'Flip Sign';
            app.CorrectionforMultipleComparisonsDropDownLabel = uilabel(app.VLSMTab);
            app.CorrectionforMultipleComparisonsDropDownLabel.Text = {'Correction for '; 'Multiple Comparisons'};
            app.DropDown_MultipleComp = uidropdown(app.VLSMTab);
            app.DropDown_MultipleComp.Items = {'Permutations', 'FDR', 'None / lenient', 'Cluster-based Perm', 'Bonferroni'};
            app.DropDown_MultipleComp.Value = 'Permutations';
            app.MinimalOverlapEditFieldLabel = uilabel(app.VLSMTab);
            app.MinimalOverlapEditFieldLabel.Text = 'Minimal Overlap';
            app.EditField_minSubj = uieditfield(app.VLSMTab, 'numeric');
            app.EditField_minSubj.Limits = [1 Inf];
            app.EditField_minSubj.RoundFractionalValues = 'on';
            app.EditField_minSubj.ValueDisplayFormat = '%.0f';
            app.EditField_minSubj.Value = 1;
            app.permutationsnumberEditFieldLabel = uilabel(app.VLSMTab);
            app.permutationsnumberEditFieldLabel.Text = 'permutations number';
            app.EditField_nperm = uieditfield(app.VLSMTab, 'numeric');
            app.EditField_nperm.Limits = [0 Inf];
            app.EditField_nperm.RoundFractionalValues = 'on';
            app.EditField_nperm.ValueDisplayFormat = '%.0f';
            app.PeakvoxelvLabel = uilabel(app.VLSMTab);
            app.PeakvoxelvLabel.Text = 'Peak voxel (v) ';
            app.EditField_v = uieditfield(app.VLSMTab, 'numeric');
            app.EditField_v.Limits = [1 100];
            app.EditField_v.RoundFractionalValues = 'on';
            app.EditField_v.ValueDisplayFormat = '%.0f';
            app.EditField_v.Value = 1;
            app.CheckBox_VolumeCorrection = uicheckbox(app.VLSMTab);
            app.CheckBox_VolumeCorrection.Text = 'Apply Lesion volume correction';
            app.MinimalclustersizeEditFieldLabel = uilabel(app.VLSMTab);
            app.MinimalclustersizeEditFieldLabel.Text = 'Minimal cluster size';
            app.EditField_minClustSize = uieditfield(app.VLSMTab, 'numeric');
            app.EditField_minClustSize.Limits = [1 Inf];
            app.EditField_minClustSize.RoundFractionalValues = 'on';
            app.EditField_minClustSize.ValueDisplayFormat = '%.0f';
            app.EditField_minClustSize.Value = 10;
            app.SignificanceLevelEditFieldLabel = uilabel(app.VLSMTab);
            app.SignificanceLevelEditFieldLabel.Text = 'Significance Level';
            app.EditField_Significance = uieditfield(app.VLSMTab, 'numeric');
            app.EditField_Significance.Limits = [0 1];
            app.EditField_Significance.Value = 0.05;
            app.CheckBox_mask = uicheckbox(app.VLSMTab);
            app.CheckBox_mask.Text = 'Apply mask';
            % Show the figure after all components are created
            app.BrainLabFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainlab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainLabFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainLabFigure)
        end
    end
end